classdef DISCO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DISCOProtocolUIFigure    matlab.ui.Figure
        SubjectIDEditField       matlab.ui.control.EditField
        SubjectIDEditFieldLabel  matlab.ui.control.Label
        ForagingButton           matlab.ui.control.Button
        NBackButton              matlab.ui.control.Button
        FlankerButton            matlab.ui.control.Button
        CueReactivityButton      matlab.ui.control.Button
        DelayDiscountingButton   matlab.ui.control.Button
        BARTButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        subjectID
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BARTButton
        function BARTButtonPushed(app, event)
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/BART')
            BART(app.subjectID)
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            app.subjectID = char(app.SubjectIDEditField.Value);
        end

        % Button pushed function: FlankerButton
        function FlankerButtonPushed(app, event)
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/Flanker/');
            Flanker(app.subjectID);
        end

        % Button pushed function: DelayDiscountingButton
        function DelayDiscountingButtonPushed(app, event)
            addpath('home/helpdesk/Documents/MATLAB/RNI-OUD/Delay Discounting/');
            Adaptive_Delay_Discounting(app.subjectID);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DISCOProtocolUIFigure = uifigure('Visible', 'off');
            app.DISCOProtocolUIFigure.Name = 'DISCO Protocol';
            app.BARTButton = uibutton(app.DISCOProtocolUIFigure, 'push');
            app.BARTButton.ButtonPushedFcn = createCallbackFcn(app, @BARTButtonPushed, true);
            app.BARTButton.Text = 'BART';
            app.DelayDiscountingButton = uibutton(app.DISCOProtocolUIFigure, 'push');
            app.DelayDiscountingButton.ButtonPushedFcn = createCallbackFcn(app, @DelayDiscountingButtonPushed, true);
            app.DelayDiscountingButton.Text = 'Delay Discounting';
            app.CueReactivityButton = uibutton(app.DISCOProtocolUIFigure, 'push');
            app.CueReactivityButton.Text = 'Cue Reactivity';
            app.FlankerButton = uibutton(app.DISCOProtocolUIFigure, 'push');
            app.FlankerButton.ButtonPushedFcn = createCallbackFcn(app, @FlankerButtonPushed, true);
            app.FlankerButton.Text = 'Flanker';
            app.NBackButton = uibutton(app.DISCOProtocolUIFigure, 'push');
            app.NBackButton.Text = 'N Back';
            app.ForagingButton = uibutton(app.DISCOProtocolUIFigure, 'push');
            app.ForagingButton.Text = 'Foraging';
            app.SubjectIDEditFieldLabel = uilabel(app.DISCOProtocolUIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.DISCOProtocolUIFigure, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.SubjectIDEditField.Placeholder = 'Input ID';
            % Show the figure after all components are created
            app.DISCOProtocolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DISCO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DISCOProtocolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DISCOProtocolUIFigure)
        end
    end
end