classdef J_Scan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea_13                     matlab.ui.control.TextArea
        DevelopedbyJacobSuffridgeLabel  matlab.ui.control.Label
        ProtocolIDEditField             matlab.ui.control.EditField
        SessionIDEditField              matlab.ui.control.EditField
        SessionIDEditFieldLabel         matlab.ui.control.Label
        SubjectIDEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        TextArea2_2                     matlab.ui.control.TextArea
        TextArea2                       matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        BARTTab                         matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
        ButtonGroup_BART                matlab.ui.container.ButtonGroup
        DISCOButton                     matlab.ui.control.RadioButton
        OUDWearablesButton              matlab.ui.control.RadioButton
        TMSOUDButton                    matlab.ui.control.RadioButton
        DBSOUDButton                    matlab.ui.control.RadioButton
        RunBARTButton                   matlab.ui.control.Button
        CRTab                           matlab.ui.container.Tab
        ConditionDropDown_DD_2          matlab.ui.control.DropDown
        ConditionDropDownLabel_2        matlab.ui.control.Label
        TextArea_15                     matlab.ui.control.TextArea
        RunCRDEMOButton                 matlab.ui.control.Button
        TextArea_9                      matlab.ui.control.TextArea
        ButtonGroup_CR                  matlab.ui.container.ButtonGroup
        FUSOUDButton                    matlab.ui.control.RadioButton
        DISCOButton_3                   matlab.ui.control.RadioButton
        OUDWearablesButton_3            matlab.ui.control.RadioButton
        TMSOUDButton_3                  matlab.ui.control.RadioButton
        DBSOUDButton_3                  matlab.ui.control.RadioButton
        RunCueReactivityButton          matlab.ui.control.Button
        DDTab                           matlab.ui.container.Tab
        RunDemoButton                   matlab.ui.control.Button
        Run5TrialDDButton               matlab.ui.control.Button
        ConditionDropDown_DD            matlab.ui.control.DropDown
        ConditionDropDownLabel          matlab.ui.control.Label
        TextArea_14                     matlab.ui.control.TextArea
        TextArea_7                      matlab.ui.control.TextArea
        TrainingFileDropDown            matlab.ui.control.DropDown
        TrainingFileDropDownLabel       matlab.ui.control.Label
        ButtonGroup_DD                  matlab.ui.container.ButtonGroup
        InsideOutButton                 matlab.ui.control.RadioButton
        DISCOButton_2                   matlab.ui.control.RadioButton
        OUDWearablesButton_2            matlab.ui.control.RadioButton
        TMSOUDButton_2                  matlab.ui.control.RadioButton
        DBSOUDButton_2                  matlab.ui.control.RadioButton
        RunDelayDiscountButton          matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        DotProbeTab                     matlab.ui.container.Tab
        FlankerTab                      matlab.ui.container.Tab
        ButtonGroup_Flanker             matlab.ui.container.ButtonGroup
        DISCOButton_4                   matlab.ui.control.RadioButton
        FUSOUDButton_2                  matlab.ui.control.RadioButton
        TextArea_10                     matlab.ui.control.TextArea
        RunFlankerButton                matlab.ui.control.Button
        ForagingTab                     matlab.ui.container.Tab
        ButtonGroup_Foraging            matlab.ui.container.ButtonGroup
        DISCOButton_5                   matlab.ui.control.RadioButton
        TextArea_11                     matlab.ui.control.TextArea
        RunForagingButton               matlab.ui.control.Button
        NBackTab                        matlab.ui.container.Tab
        TextArea_12                     matlab.ui.control.TextArea
        ButtonGroup_NBack               matlab.ui.container.ButtonGroup
        InsideOutButton_2               matlab.ui.control.RadioButton
        DISCOButton_6                   matlab.ui.control.RadioButton
        TMSOUDButton_4                  matlab.ui.control.RadioButton
        RunNBackButton                  matlab.ui.control.Button
        PVTTab                          matlab.ui.container.Tab
        StroopTab                       matlab.ui.container.Tab
        RunStroopButton                 matlab.ui.control.Button
        CueVideoTab                     matlab.ui.container.Tab
        RunCueVideoButton               matlab.ui.control.Button
    end


    properties (Access = private)
        subjectID  % subjectID input by the user
        sessionID  % sessionID input by the user
        protocolID % protocolID is toggeled by the user (updates in each tab)
        tmp
        switchMRI  % switchMRI is the toggle switch for the user
        DD_trainingFilePath % store the name of the training file to load for Delay Discounting
        DD_configFile % store the config file for Delay Discounting
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add path to task functions
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/BART')
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/Delay Discounting');
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/')
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/Flanker/');
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/Foraging');
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/NBack');
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/Cue Reactivity');
            addpath('/home/helpdesk/Documents/MATLAB/RNI-OUD/Stroop');

            % Set the initial DD condition at start up
            app.DD_configFile = app.ConditionDropDown_DD.Value;
        end

        % Button pushed function: RunBARTButton
        function RunBARTButtonPushed(app, event)
            BART(app.subjectID,app.sessionID,app.protocolID);
        end

        % Value changed function: SessionIDEditField
        function SessionIDEditFieldValueChanged(app, event)
            app.sessionID = app.SessionIDEditField.Value;
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            % Pull the sessionID from the edit field
            app.subjectID = app.SubjectIDEditField.Value;

            % Get the training files
            DD_path = ['/home/helpdesk/Documents/MATLAB/RNI-OUD/Delay Discounting/Delay Discounting Data/',app.protocolID,'/',app.subjectID];
            d_DD = dir([DD_path, '/*5Trial*.mat']);

            % Populate the training files in the dropdown box in ascending order
            app.TrainingFileDropDown.Items = flip({d_DD.name});

            % Store the path to the training file
            app.DD_trainingFilePath = [DD_path, '/', app.TrainingFileDropDown.Value];
        end

        % Callback function
        function RunCRTrainingButtonPushed(app, event)
            CR_training_slider(app.subjectID,app.sessionID,app.protocolID,{})
        end

        % Button pushed function: RunCueReactivityButton
        function RunCueReactivityButtonPushed(app, event)
%             Cue_Reactivity_MRI_slider(app.subjectID,app.sessionID,'Weighted');
            CR_training_slider_triggers(app.subjectID, app.sessionID, app.protocolID, {});
        end

        % Button pushed function: RunForagingButton
        function RunForagingButtonPushed(app, event)
            Foraging(app.subjectID);
        end

        % Selection changed function: ButtonGroup_BART
        function ButtonGroup_BARTSelectionChanged(app, event)
            % Update the protocol ID field when the radio button changes
            app.protocolID = app.ButtonGroup_BART.SelectedObject.Text;
            app.ProtocolIDEditField.Value = ['Protocol ID: ', app.protocolID];
        end

        % Button pushed function: Run5TrialDDButton
        function Run5TrialDDButtonPushed(app, event)
            Delay_Discounting_5Trial(app.subjectID,app.sessionID,app.protocolID);
        end

        % Selection changed function: ButtonGroup_DD
        function ButtonGroup_DDSelectionChanged(app, event)
            % Update the protocol ID field when the radio button changes
            app.protocolID = app.ButtonGroup_DD.SelectedObject.Text;
            app.ProtocolIDEditField.Value = ['Protocol ID: ', app.protocolID];

            SubjectIDEditFieldValueChanged(app, event)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Get the name of the selected tab group
            app.tmp = app.TabGroup.SelectedTab.Title;
            app.tmp = ['ButtonGroup_' app.tmp];

            % Update the protocol ID field when the tab changes
            app.ProtocolIDEditField.Value = ['Protocol ID: ', app.(app.tmp).SelectedObject.Text];
            app.protocolID = app.(app.tmp).SelectedObject.Text;

            SubjectIDEditFieldValueChanged(app,event);
        end

        % Selection changed function: ButtonGroup_CR
        function ButtonGroup_CRSelectionChanged(app, event)
            % Update the protocol ID field when the radio button changes
            app.protocolID = app.ButtonGroup_CR.SelectedObject.Text;
            app.ProtocolIDEditField.Value = ['Protocol ID: ', app.protocolID];

        end

        % Selection changed function: ButtonGroup_Flanker
        function ButtonGroup_FlankerSelectionChanged(app, event)
            % Update the protocol ID field when the radio button changes
            app.protocolID = app.ButtonGroup_Flanker.SelectedObject.Text;
            app.ProtocolIDEditField.Value = ['Protocol ID: ', app.protocolID];

        end

        % Selection changed function: ButtonGroup_Foraging
        function ButtonGroup_ForagingSelectionChanged(app, event)
            % Update the protocol ID field when the radio button changes
            app.protocolID = app.ButtonGroup_Foraging.SelectedObject.Text;
            app.ProtocolIDEditField.Value = ['Protocol ID: ', app.protocolID];
        end

        % Selection changed function: ButtonGroup_NBack
        function ButtonGroup_NBackSelectionChanged(app, event)
            % Update the protocol ID field when the radio button changes
            app.protocolID = app.ButtonGroup_NBack.SelectedObject.Text;
            app.ProtocolIDEditField.Value = ['Protocol ID: ', app.protocolID];
        end

        % Callback function
        function MRISwitchValueChanged(app, event)
            app.switchMRI = app.MRISwitch.Value;
        end

        % Button pushed function: RunDelayDiscountButton
        function RunDelayDiscountButtonPushed(app, event)
            Delay_Discounting_LFP(app.subjectID,app.sessionID,app.protocolID,app.DD_trainingFilePath);
        end

        % Button pushed function: RunNBackButton
        function RunNBackButtonPushed(app, event)
            Nback(app.subjectID,app.sessionID,app.protocolID);
        end

        % Button pushed function: RunStroopButton
        function RunStroopButtonPushed(app, event)
            Stroop(app.subjectID,app.sessionID,app.protocolID);
        end

        % Button pushed function: RunCueVideoButton
        function RunCueVideoButtonPushed(app, event)
            Cue_Reactivity_videos_slider(app.subjectID,app.sessionID)
        end

        % Button pushed function: RunFlankerButton
        function RunFlankerButtonPushed(app, event)
            Flanker(app.subjectID,app.sessionID,app.protocolID);
        end

        % Value changed function: ConditionDropDown_DD
        function ConditionDropDown_DDValueChanged(app, event)
            app.DD_configFile = app.ConditionDropDown_DD.Value;
        end

        % Button pushed function: RunDemoButton
        function RunDemoButtonPushed(app, event)
            Delay_Discounting_5Trial_DEMO
        end

        % Value changed function: TrainingFileDropDown
        function TrainingFileDropDownValueChanged(app, event)
            % Store the path to the training file
            SubjectIDEditFieldValueChanged(app,event);

        end

        % Button pushed function: RunCRDEMOButton
        function RunCRDEMOButtonPushed(app, event)
            CR_training_slider_DEMO
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.BARTTab = uitab(app.TabGroup);
            app.BARTTab.Title = 'BART';
            app.RunBARTButton = uibutton(app.BARTTab, 'push');
            app.RunBARTButton.ButtonPushedFcn = createCallbackFcn(app, @RunBARTButtonPushed, true);
            app.RunBARTButton.Text = 'Run BART';
            app.ButtonGroup_BART = uibuttongroup(app.BARTTab);
            app.ButtonGroup_BART.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_BARTSelectionChanged, true);
            app.DBSOUDButton = uiradiobutton(app.ButtonGroup_BART);
            app.DBSOUDButton.Text = 'DBS-OUD';
            app.DBSOUDButton.Value = true;
            app.TMSOUDButton = uiradiobutton(app.ButtonGroup_BART);
            app.TMSOUDButton.Text = 'TMS-OUD';
            app.OUDWearablesButton = uiradiobutton(app.ButtonGroup_BART);
            app.OUDWearablesButton.Text = 'OUD Wearables';
            app.DISCOButton = uiradiobutton(app.ButtonGroup_BART);
            app.DISCOButton.Text = 'DISCO';
            app.TextArea_2 = uitextarea(app.BARTTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Select which version of BART you would like to run'};
            app.CRTab = uitab(app.TabGroup);
            app.CRTab.Title = 'CR';
            app.RunCueReactivityButton = uibutton(app.CRTab, 'push');
            app.RunCueReactivityButton.ButtonPushedFcn = createCallbackFcn(app, @RunCueReactivityButtonPushed, true);
            app.RunCueReactivityButton.Text = 'Run Cue Reactivity';
            app.ButtonGroup_CR = uibuttongroup(app.CRTab);
            app.ButtonGroup_CR.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_CRSelectionChanged, true);
            app.DBSOUDButton_3 = uiradiobutton(app.ButtonGroup_CR);
            app.DBSOUDButton_3.Text = 'DBS-OUD';
            app.DBSOUDButton_3.Value = true;
            app.TMSOUDButton_3 = uiradiobutton(app.ButtonGroup_CR);
            app.TMSOUDButton_3.Text = 'TMS-OUD';
            app.OUDWearablesButton_3 = uiradiobutton(app.ButtonGroup_CR);
            app.OUDWearablesButton_3.Text = 'OUD Wearables';
            app.DISCOButton_3 = uiradiobutton(app.ButtonGroup_CR);
            app.DISCOButton_3.Text = 'DISCO';
            app.FUSOUDButton = uiradiobutton(app.ButtonGroup_CR);
            app.FUSOUDButton.Text = 'FUS-OUD';
            app.TextArea_9 = uitextarea(app.CRTab);
            app.TextArea_9.Editable = 'off';
            app.TextArea_9.Value = {'Select which version of '; 'Cue Reactivity you would like to run'};
            app.RunCRDEMOButton = uibutton(app.CRTab, 'push');
            app.RunCRDEMOButton.ButtonPushedFcn = createCallbackFcn(app, @RunCRDEMOButtonPushed, true);
            app.RunCRDEMOButton.Text = 'Run CR DEMO';
            app.TextArea_15 = uitextarea(app.CRTab);
            app.TextArea_15.Value = {'Select the condition to run Cue Reactivity'; 'with'};
            app.ConditionDropDownLabel_2 = uilabel(app.CRTab);
            app.ConditionDropDownLabel_2.Text = 'Condition';
            app.ConditionDropDown_DD_2 = uidropdown(app.CRTab);
            app.ConditionDropDown_DD_2.Items = {'Behavioral', 'MRI ', 'LFP'};
            app.ConditionDropDown_DD_2.Value = 'Behavioral';
            app.DDTab = uitab(app.TabGroup);
            app.DDTab.Title = 'DD';
            app.TextArea_4 = uitextarea(app.DDTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Select which version of Delay Discount to run'};
            app.RunDelayDiscountButton = uibutton(app.DDTab, 'push');
            app.RunDelayDiscountButton.ButtonPushedFcn = createCallbackFcn(app, @RunDelayDiscountButtonPushed, true);
            app.RunDelayDiscountButton.Text = 'Run Delay Discount';
            app.ButtonGroup_DD = uibuttongroup(app.DDTab);
            app.ButtonGroup_DD.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_DDSelectionChanged, true);
            app.DBSOUDButton_2 = uiradiobutton(app.ButtonGroup_DD);
            app.DBSOUDButton_2.Text = 'DBS-OUD';
            app.DBSOUDButton_2.Value = true;
            app.TMSOUDButton_2 = uiradiobutton(app.ButtonGroup_DD);
            app.TMSOUDButton_2.Text = 'TMS-OUD';
            app.OUDWearablesButton_2 = uiradiobutton(app.ButtonGroup_DD);
            app.OUDWearablesButton_2.Text = 'OUD Wearables';
            app.DISCOButton_2 = uiradiobutton(app.ButtonGroup_DD);
            app.DISCOButton_2.Text = 'DISCO';
            app.InsideOutButton = uiradiobutton(app.ButtonGroup_DD);
            app.InsideOutButton.Text = 'Inside Out';
            app.TrainingFileDropDownLabel = uilabel(app.DDTab);
            app.TrainingFileDropDownLabel.Text = 'Training File';
            app.TrainingFileDropDown = uidropdown(app.DDTab);
            app.TrainingFileDropDown.Items = {'Searching...'};
            app.TrainingFileDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainingFileDropDownValueChanged, true);
            app.TrainingFileDropDown.Placeholder = 'Searching...';
            app.TrainingFileDropDown.Value = 'Searching...';
            app.TextArea_7 = uitextarea(app.DDTab);
            app.TextArea_7.Editable = 'off';
            app.TextArea_7.Value = {'Select the training file '; '(if applicable)'};
            app.TextArea_14 = uitextarea(app.DDTab);
            app.TextArea_14.Value = {'Select the condition to run Delay Discounting '; 'with'};
            app.ConditionDropDownLabel = uilabel(app.DDTab);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown_DD = uidropdown(app.DDTab);
            app.ConditionDropDown_DD.Items = {'Behavioral', 'MRI ', 'LFP'};
            app.ConditionDropDown_DD.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDown_DDValueChanged, true);
            app.ConditionDropDown_DD.Value = 'Behavioral';
            app.Run5TrialDDButton = uibutton(app.DDTab, 'push');
            app.Run5TrialDDButton.ButtonPushedFcn = createCallbackFcn(app, @Run5TrialDDButtonPushed, true);
            app.Run5TrialDDButton.Text = 'Run 5 Trial DD';
            app.RunDemoButton = uibutton(app.DDTab, 'push');
            app.RunDemoButton.ButtonPushedFcn = createCallbackFcn(app, @RunDemoButtonPushed, true);
            app.RunDemoButton.Text = 'Run Demo';
            app.DotProbeTab = uitab(app.TabGroup);
            app.DotProbeTab.Title = 'Dot Probe';
            app.FlankerTab = uitab(app.TabGroup);
            app.FlankerTab.Title = 'Flanker';
            app.RunFlankerButton = uibutton(app.FlankerTab, 'push');
            app.RunFlankerButton.ButtonPushedFcn = createCallbackFcn(app, @RunFlankerButtonPushed, true);
            app.RunFlankerButton.Text = 'Run Flanker';
            app.TextArea_10 = uitextarea(app.FlankerTab);
            app.TextArea_10.Editable = 'off';
            app.TextArea_10.Value = {'Select which version of Flanker you would like to run'};
            app.ButtonGroup_Flanker = uibuttongroup(app.FlankerTab);
            app.ButtonGroup_Flanker.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_FlankerSelectionChanged, true);
            app.FUSOUDButton_2 = uiradiobutton(app.ButtonGroup_Flanker);
            app.FUSOUDButton_2.Text = 'FUS-OUD';
            app.FUSOUDButton_2.Value = true;
            app.DISCOButton_4 = uiradiobutton(app.ButtonGroup_Flanker);
            app.DISCOButton_4.Text = 'DISCO';
            app.ForagingTab = uitab(app.TabGroup);
            app.ForagingTab.Title = 'Foraging';
            app.RunForagingButton = uibutton(app.ForagingTab, 'push');
            app.RunForagingButton.ButtonPushedFcn = createCallbackFcn(app, @RunForagingButtonPushed, true);
            app.RunForagingButton.Text = 'Run Foraging';
            app.TextArea_11 = uitextarea(app.ForagingTab);
            app.TextArea_11.Editable = 'off';
            app.TextArea_11.Value = {'Select which version of Foraging you would like to run'};
            app.ButtonGroup_Foraging = uibuttongroup(app.ForagingTab);
            app.ButtonGroup_Foraging.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_ForagingSelectionChanged, true);
            app.DISCOButton_5 = uiradiobutton(app.ButtonGroup_Foraging);
            app.DISCOButton_5.Text = 'DISCO';
            app.DISCOButton_5.Value = true;
            app.NBackTab = uitab(app.TabGroup);
            app.NBackTab.Title = 'N Back';
            app.RunNBackButton = uibutton(app.NBackTab, 'push');
            app.RunNBackButton.ButtonPushedFcn = createCallbackFcn(app, @RunNBackButtonPushed, true);
            app.RunNBackButton.Text = 'Run N Back';
            app.ButtonGroup_NBack = uibuttongroup(app.NBackTab);
            app.ButtonGroup_NBack.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_NBackSelectionChanged, true);
            app.TMSOUDButton_4 = uiradiobutton(app.ButtonGroup_NBack);
            app.TMSOUDButton_4.Text = 'TMS-OUD';
            app.TMSOUDButton_4.Value = true;
            app.DISCOButton_6 = uiradiobutton(app.ButtonGroup_NBack);
            app.DISCOButton_6.Text = 'DISCO';
            app.InsideOutButton_2 = uiradiobutton(app.ButtonGroup_NBack);
            app.InsideOutButton_2.Text = 'Inside Out';
            app.TextArea_12 = uitextarea(app.NBackTab);
            app.TextArea_12.Editable = 'off';
            app.TextArea_12.Value = {'Select which version of'; 'N Back you would like to run'};
            app.PVTTab = uitab(app.TabGroup);
            app.PVTTab.Title = 'PVT';
            app.StroopTab = uitab(app.TabGroup);
            app.StroopTab.Title = 'Stroop';
            app.RunStroopButton = uibutton(app.StroopTab, 'push');
            app.RunStroopButton.ButtonPushedFcn = createCallbackFcn(app, @RunStroopButtonPushed, true);
            app.RunStroopButton.Text = 'Run Stroop';
            app.CueVideoTab = uitab(app.TabGroup);
            app.CueVideoTab.Title = 'Cue Video';
            app.RunCueVideoButton = uibutton(app.CueVideoTab, 'push');
            app.RunCueVideoButton.ButtonPushedFcn = createCallbackFcn(app, @RunCueVideoButtonPushed, true);
            app.RunCueVideoButton.Text = 'Run Cue Video';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {''; 'J-SCAN'};
            app.TextArea2_2 = uitextarea(app.UIFigure);
            app.TextArea2_2.Editable = 'off';
            app.TextArea2_2.Value = {'The purpose of OUD App was to develop a user friendly application to facilitate task implementation across all OUD protocols at WVU RNI. This app executes 7+ tasks, each containing options to adjust for different study configurations. Task data is automatically scored/processed at runtime and pdf reports are generated detailing task behavioral results. '};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'RNI_logo.png');
            app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.SubjectIDEditField.Placeholder = 'Input subject ID';
            app.SessionIDEditFieldLabel = uilabel(app.UIFigure);
            app.SessionIDEditFieldLabel.Text = 'Session ID';
            app.SessionIDEditField = uieditfield(app.UIFigure, 'text');
            app.SessionIDEditField.ValueChangedFcn = createCallbackFcn(app, @SessionIDEditFieldValueChanged, true);
            app.SessionIDEditField.Placeholder = 'Input Session ID';
            app.ProtocolIDEditField = uieditfield(app.UIFigure, 'text');
            app.ProtocolIDEditField.Value = 'Protocol ID: DBS-OUD';
            app.DevelopedbyJacobSuffridgeLabel = uilabel(app.UIFigure);
            app.DevelopedbyJacobSuffridgeLabel.Text = 'Developed by Jacob Suffridge';
            app.TextArea_13 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = J_Scan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end